classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RightButton         matlab.ui.control.Button
        LeftButton          matlab.ui.control.Button
        DownButton          matlab.ui.control.Button
        UpButton            matlab.ui.control.Button
        DoubleStopCheckBox  matlab.ui.control.CheckBox
        IgnitionCheckBox    matlab.ui.control.CheckBox
        StopButton          matlab.ui.control.Button
        StartButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        %  starts here, insert everything that we require.

        modelR3; % insert UR3 here

        % Insert second robot here

        q0 = [0,0,0,0,0,0];
        qUR3 = [0,pi,pi/2,0,0,-pi];

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            
            app.modelR3 = UR3;
            % app.modelR3.base = transl([0.5,0,0]); 
            app.modelR3.model.animate(app.q0);
            drawnow();
            hold on;   

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            app.StartButton.Enable = true;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.DoubleStopCheckBox.Value=1;
            app.IgnitionCheckBox.Value=0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

                if app.DoubleStopCheckBox.Value==0 && app.IgnitionCheckBox.Value==1
                        qMatrix=jtraj(app.q0,app.qUR3,50);
                        for robotStepIndex = 1:size(qMatrix,1)
                            
                            app.modelR3.model.animate(qMatrix(robotStepIndex,:));
                            drawnow();
                        end
                end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            upT = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,0,0.1);
            upQ = app.modelR3.model.ikcon(upT);

            
            upMatrix = jtraj(app.modelR3.model.getpos(),upQ,20);
            % display(upMatrix)
            for robotStepIndex = 1:size(upMatrix,1)
                app.modelR3.model.animate(upMatrix(robotStepIndex,:));
                drawnow();
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            rightT = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0.1,0,0);
            rightQ = app.modelR3.model.ikcon(rightT);

            
            rightMatrix = jtraj(app.modelR3.model.getpos(),rightQ,20);
            % display(upMatrix)
            for robotStepIndex = 1:size(rightMatrix,1)
                app.modelR3.model.animate(rightMatrix(robotStepIndex,:));
                drawnow();
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            leftT = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(-0.1,0,0);
            leftQ = app.modelR3.model.ikcon(leftT);

            
            leftMatrix = jtraj(app.modelR3.model.getpos(),leftQ,20);
            % display(upMatrix)
            for robotStepIndex = 1:size(leftMatrix,1)
                app.modelR3.model.animate(leftMatrix(robotStepIndex,:));
                drawnow();
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            downT = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,0,-0.1);
            downQ = app.modelR3.model.ikcon(downT);

            
            downMatrix = jtraj(app.modelR3.model.getpos(),downQ,20);
            % display(upMatrix)
            for robotStepIndex = 1:size(downMatrix,1)
                app.modelR3.model.animate(downMatrix(robotStepIndex,:));
                drawnow();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.IgnitionCheckBox = uicheckbox(app.UIFigure);
            app.IgnitionCheckBox.Text = 'Ignition';
            app.DoubleStopCheckBox = uicheckbox(app.UIFigure);
            app.DoubleStopCheckBox.Text = 'Double Stop';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end